classdef App_ayuda < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        AyudaLabel          matlab.ui.control.Label
        MtodoDropDownLabel  matlab.ui.control.Label
        MtodoDropDown       matlab.ui.control.DropDown
        MuchotextoLabel     matlab.ui.control.Label
    end

    
    methods (Access = public)
        
        function actualizar_texto(app, value)
            switch value
                case "Incremental"
                    app.MuchotextoLabel.Text = "Este método es una mierda";
                case "Secante"
                    app.MuchotextoLabel.Text = "Este no tanto";
                case "Newton"
                    app.MuchotextoLabel.Text = "Este me gusta";
                case "Regla Falsa"
                    app.MuchotextoLabel.Text = "Este también";
                case "Bisección"
                    app.MuchotextoLabel.Text = "Este es engorroso";
                case "Punto Fijo"
                    app.MuchotextoLabel.Text = "Este es chido";
                case "Raices Múltiples"
                    app.MuchotextoLabel.Text = "Este me aburre";
                otherwise
                    app.MuchotextoLabel.Text = "Seleccione un método";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, metodo)
            app.MtodoDropDown.Value = metodo;
            actualizar_texto(app, metodo);
        end

        % Value changed function: MtodoDropDown
        function MtodoDropDownValueChanged(app, event)
            value = app.MtodoDropDown.Value;
            actualizar_texto(app, value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AyudaLabel = uilabel(app.UIFigure);
            app.AyudaLabel.Text = 'Ayuda';
            app.MtodoDropDownLabel = uilabel(app.UIFigure);
            app.MtodoDropDownLabel.Text = 'Método';
            app.MtodoDropDown = uidropdown(app.UIFigure);
            app.MtodoDropDown.Items = {'Incremental', 'Secante', 'Newton', 'Regla Falsa', 'Bisección', 'Punto Fijo', 'Raices Mútiples'};
            app.MtodoDropDown.ValueChangedFcn = createCallbackFcn(app, @MtodoDropDownValueChanged, true);
            app.MtodoDropDown.Value = 'Incremental';
            app.MuchotextoLabel = uilabel(app.UIFigure);
            app.MuchotextoLabel.Text = 'Mucho texto';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_ayuda(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end