classdef App_biseccion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BiseccinUIFigure           matlab.ui.Figure
        UITable                    matlab.ui.control.Table
        UIAxes                     matlab.ui.control.UIAxes
        FuncinEditFieldLabel       matlab.ui.control.Label
        FuncinEditField            matlab.ui.control.EditField
        XiEditFieldLabel           matlab.ui.control.Label
        XiEditField                matlab.ui.control.NumericEditField
        XsEditFieldLabel           matlab.ui.control.Label
        XsEditField                matlab.ui.control.NumericEditField
        ToleranciaEditFieldLabel   matlab.ui.control.Label
        ToleranciaEditField        matlab.ui.control.NumericEditField
        IteracionesEditFieldLabel  matlab.ui.control.Label
        IteracionesEditField       matlab.ui.control.NumericEditField
        ErrorSwitchLabel           matlab.ui.control.Label
        ErrorSwitch                matlab.ui.control.ToggleSwitch
        EjecutarButton             matlab.ui.control.Button
        MTODOBISECCINLabel         matlab.ui.control.Label
    end

    
    methods (Access = public)
        
        function [resultado] = reglafalsa_metodo(app,func,a,b,tol,iter,err)
            resultado = [];
            f = inline(func);
            fa = f(a);
            fb = f(b);
            
            errf = "abs(a-b)";
            if err == "Relativo"
                errf = "abs((a-b)/a)";
            end
            er = inline(errf);
            
            if fa == 0
                msgbox(sprintf('%g es una raiz', a));
            elseif fb == 0
                msgbox(sprintf('%g es una raiz', b));
            elseif tol <= 0
                msgbox(sprintf('Ingrese una tolerancia válida'));
            elseif iter <= 0
                msgbox(sprintf('Ingrese un numero de iteraciones válida'));
            else
                i = 1;
                error = tol + 1;
                m = (a+b)/2;
                fm = f(m);
            
                resultado = [[i,a,b,m,fm,"---"];];
            
                while fm ~= 0 && error > tol && i < iter
                    if fa*fm < 0
                        b = m;
                        fb = fm;
                    else
                        a = m;
                        fa = fm;
                    end
                    aux = m;
                    m = (a+b)/2;
                    fm = f(m);
                    i = i + 1;
                    error = er(m,aux);
                    resultado = [resultado;{i,a,b,m,fm,error};];
                end
                
                if error < tol
                    msgbox(sprintf('%g es raiz aproximada',m));
                end
            
                if fm == 0
                    msgbox(sprintf('%g es raiz',m));
                end
            
                if error > tol
                    msgbox(sprintf('Fallo para conseguir tolerancia de %g en %g iteraciones.', tol,iter));
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
            func = app.FuncinEditField.Value;
            if func == ""
                msgbox(sprintf("Ingrese una funcion."));
            else
                fplot(app.UIAxes,func);


                xi = app.XiEditField.Value;
                xs = app.XsEditField.Value;
                tol= app.ToleranciaEditField.Value;
                iter = app.IteracionesEditField.Value;
                err = app.ErrorSwitch.Value;
                
                t = reglafalsa_metodo(app,func,xi,xs,tol,iter,err);
                app.UITable.Data = t;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BiseccinUIFigure = uifigure('Visible', 'off');
            app.BiseccinUIFigure.Name = 'Bisección';
            app.UITable = uitable(app.BiseccinUIFigure);
            app.UITable.ColumnName = {'n'; 'xi'; 'xs'; 'xm'; 'f(m)'; 'Error'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.BiseccinUIFigure);
            title(app.UIAxes, 'f(x)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.FuncinEditFieldLabel = uilabel(app.BiseccinUIFigure);
            app.FuncinEditFieldLabel.Text = 'Función';
            app.FuncinEditField = uieditfield(app.BiseccinUIFigure, 'text');
            app.XiEditFieldLabel = uilabel(app.BiseccinUIFigure);
            app.XiEditFieldLabel.Text = 'Xi';
            app.XiEditField = uieditfield(app.BiseccinUIFigure, 'numeric');
            app.XsEditFieldLabel = uilabel(app.BiseccinUIFigure);
            app.XsEditFieldLabel.Text = 'Xs';
            app.XsEditField = uieditfield(app.BiseccinUIFigure, 'numeric');
            app.ToleranciaEditFieldLabel = uilabel(app.BiseccinUIFigure);
            app.ToleranciaEditFieldLabel.Text = 'Tolerancia';
            app.ToleranciaEditField = uieditfield(app.BiseccinUIFigure, 'numeric');
            app.IteracionesEditFieldLabel = uilabel(app.BiseccinUIFigure);
            app.IteracionesEditFieldLabel.Text = 'Iteraciones';
            app.IteracionesEditField = uieditfield(app.BiseccinUIFigure, 'numeric');
            app.ErrorSwitchLabel = uilabel(app.BiseccinUIFigure);
            app.ErrorSwitchLabel.Text = 'Error';
            app.ErrorSwitch = uiswitch(app.BiseccinUIFigure, 'toggle');
            app.ErrorSwitch.Items = {'Relativo', 'Absoluto'};
            app.ErrorSwitch.Value = 'Relativo';
            app.EjecutarButton = uibutton(app.BiseccinUIFigure, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Text = 'Ejecutar';
            app.MTODOBISECCINLabel = uilabel(app.BiseccinUIFigure);
            app.MTODOBISECCINLabel.Text = 'MÉTODO BISECCIÓN';
            % Show the figure after all components are created
            app.BiseccinUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_biseccion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BiseccinUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BiseccinUIFigure)
        end
    end
end