classdef App_incremental < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IncrementalUIFigure        matlab.ui.Figure
        X0EditFieldLabel           matlab.ui.control.Label
        X0EditField                matlab.ui.control.NumericEditField
        DeltaEditFieldLabel        matlab.ui.control.Label
        DeltaEditField             matlab.ui.control.NumericEditField
        FuncinEditFieldLabel       matlab.ui.control.Label
        FuncinEditField            matlab.ui.control.EditField
        IteracionesEditFieldLabel  matlab.ui.control.Label
        IteracionesEditField       matlab.ui.control.NumericEditField
        UITable                    matlab.ui.control.Table
        UIAxes                     matlab.ui.control.UIAxes
        EjecutarButton             matlab.ui.control.Button
    end

    
    methods (Access = public)
        
        function [resultado] = incremental_metodo(app,func,x0,delta,iter)
            f = inline(func);
            y0 = f(x0);
            resultado = [{x0,y0};];
            
            if y0 == 0
            	msgbox(sprintf('%g es una raiz',x0));
            else
            	if delta == 0
            		msgbox(sprintf('Ingrese un delta valido'));
            	else
                    if  iter <= 0
                        fmsgbox(sprintf('Ingrese un número de iteraciones valido'));
                    else
                        contador = 1;
                        x1 = x0 + delta;
                        y1 = f(x1);
                        while y0*y1 > 0 && contador < iter
                          x0 = x1;
                          y0 = y1;
                          resultado = [resultado;{x0,y0};];
                          x1 = x0 + delta;
                          y1 = f(x1);
                          contador = contador + 1;
                        end
                        resultado = [resultado;{x1,y1};];
                        if y0*y1 < 0
                          msgbox(sprintf('[%g,%g] es un intervalo que contiene una raiz.', x0,x1));
                        elseif y1 == 0
                          msgbox(sprintf('%g es una raiz.', x1));
                        else
                          msgbox(sprintf('Fracaso en %g iteraciones.', iter));
                        end
            
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
            func = app.FuncinEditField.Value;
            if func == ""
                msgbox(sprintf("Ingrese una funcion."));
            else
                fplot(app.UIAxes,func);
            
                x0 = app.X0EditField.Value;
                delta= app.DeltaEditField.Value;
                iter = app.IteracionesEditField.Value;
                
                t = incremental_metodo(app,func,x0,delta,iter);
                app.UITable.Data = t;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IncrementalUIFigure = uifigure('Visible', 'off');
            app.IncrementalUIFigure.Name = 'Incremental';
            app.X0EditFieldLabel = uilabel(app.IncrementalUIFigure);
            app.X0EditFieldLabel.Text = 'X0';
            app.X0EditField = uieditfield(app.IncrementalUIFigure, 'numeric');
            app.DeltaEditFieldLabel = uilabel(app.IncrementalUIFigure);
            app.DeltaEditFieldLabel.Text = 'Delta';
            app.DeltaEditField = uieditfield(app.IncrementalUIFigure, 'numeric');
            app.FuncinEditFieldLabel = uilabel(app.IncrementalUIFigure);
            app.FuncinEditFieldLabel.Text = 'Función';
            app.FuncinEditField = uieditfield(app.IncrementalUIFigure, 'text');
            app.IteracionesEditFieldLabel = uilabel(app.IncrementalUIFigure);
            app.IteracionesEditFieldLabel.Text = 'Iteraciones';
            app.IteracionesEditField = uieditfield(app.IncrementalUIFigure, 'numeric');
            app.UITable = uitable(app.IncrementalUIFigure);
            app.UITable.ColumnName = {'x'; 'f(x)'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.IncrementalUIFigure);
            title(app.UIAxes, 'f(x)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.EjecutarButton = uibutton(app.IncrementalUIFigure, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Text = 'Ejecutar';
            % Show the figure after all components are created
            app.IncrementalUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_incremental

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IncrementalUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IncrementalUIFigure)
        end
    end
end