classdef App_newton < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NewtonUIFigure             matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        UITable                    matlab.ui.control.Table
        FuncinEditFieldLabel       matlab.ui.control.Label
        FuncinEditField            matlab.ui.control.EditField
        DerivadaEditFieldLabel     matlab.ui.control.Label
        DerivadaEditField          matlab.ui.control.EditField
        X0EditFieldLabel           matlab.ui.control.Label
        X0EditField                matlab.ui.control.NumericEditField
        ToleranciaEditFieldLabel   matlab.ui.control.Label
        ToleranciaEditField        matlab.ui.control.NumericEditField
        IteracionesEditFieldLabel  matlab.ui.control.Label
        IteracionesEditField       matlab.ui.control.NumericEditField
        ErrorSwitchLabel           matlab.ui.control.Label
        ErrorSwitch                matlab.ui.control.ToggleSwitch
        EjecutarButton             matlab.ui.control.Button
        MTODODENEWTONLabel         matlab.ui.control.Label
    end

    
    methods (Access = public)
        
        function [resultado] = newton_metodo(app,func,funcp,x0,tol,iter,err)
            resultado = [];
            f = inline(func);
            fp = inline(funcp);
            y0 = f(x0);
            yp0 = fp(x0);
            cont = 1;
            
            errf = "abs(a-b)";
            if err == "Relativo"
                errf = "abs((a-b)/a)";
            end
            er = inline(errf);
            
            error = tol + 1;
            resultado = [[0,x0,y0,yp0,"---"];];
            
            if y0 == 0
                msgbox(sprintf('%g es una raiz',x0));
            else
                if tol <= 0
                    msgbox(sprintf('Ingrese una tolerancia valida'));
                else
                    if iter <= 1
                        msgbox(sprintf('Ingrese un numero de iteraciones valido'));
                    else
                        while y0 ~= 0 && error > tol && cont < iter
                            xn = x0 - (y0/yp0);
                            error = er(xn,x0);
                            y0 = f(xn);
                            yp0 = fp(xn);
                            resultado = [resultado; {cont,xn,y0,yp0,error}];
                            x0 = xn;
                            cont = cont + 1;
                        end
            
                        if y0 == 0 
                            msgbox(sprintf('%g es una raiz.', x0));
                        elseif error < tol
                            msgbox(sprintf('%g es una raiz aproximada.',x0));
                        else
                            msgbox(sprintf('Fracaso en %g iteraciones.',iter));
                        end
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
            func = app.FuncinEditField.Value;
            der = app.DerivadaEditField.Value;
            if func == ""
                msgbox(sprintf("Ingrese una funcion."));
            else
                fplot(app.UIAxes,func);
                if der == ""
                    msgbox(sprintf("Ingrese la derivada de la funcion"));
                else
                    x0 = app.X0EditField.Value;
                    tol= app.ToleranciaEditField.Value;
                    iter = app.IteracionesEditField.Value;
                    err = app.ErrorSwitch.Value;
                    
                    t = newton_metodo(app,func,der,x0,tol,iter,err);
                    app.UITable.Data = t;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NewtonUIFigure = uifigure('Visible', 'off');
            app.NewtonUIFigure.Name = 'Newton';
            app.UIAxes = uiaxes(app.NewtonUIFigure);
            title(app.UIAxes, 'f(x)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UITable = uitable(app.NewtonUIFigure);
            app.UITable.ColumnName = {'n'; 'xn'; 'f(xn)'; 'd(xn)'; 'Err'};
            app.UITable.RowName = {};
            app.FuncinEditFieldLabel = uilabel(app.NewtonUIFigure);
            app.FuncinEditFieldLabel.Text = 'Función';
            app.FuncinEditField = uieditfield(app.NewtonUIFigure, 'text');
            app.DerivadaEditFieldLabel = uilabel(app.NewtonUIFigure);
            app.DerivadaEditFieldLabel.Text = 'Derivada';
            app.DerivadaEditField = uieditfield(app.NewtonUIFigure, 'text');
            app.X0EditFieldLabel = uilabel(app.NewtonUIFigure);
            app.X0EditFieldLabel.Text = 'X0';
            app.X0EditField = uieditfield(app.NewtonUIFigure, 'numeric');
            app.ToleranciaEditFieldLabel = uilabel(app.NewtonUIFigure);
            app.ToleranciaEditFieldLabel.Text = 'Tolerancia';
            app.ToleranciaEditField = uieditfield(app.NewtonUIFigure, 'numeric');
            app.IteracionesEditFieldLabel = uilabel(app.NewtonUIFigure);
            app.IteracionesEditFieldLabel.Text = 'Iteraciones';
            app.IteracionesEditField = uieditfield(app.NewtonUIFigure, 'numeric');
            app.ErrorSwitchLabel = uilabel(app.NewtonUIFigure);
            app.ErrorSwitchLabel.Text = 'Error';
            app.ErrorSwitch = uiswitch(app.NewtonUIFigure, 'toggle');
            app.ErrorSwitch.Items = {'Absoluto', 'Relativo'};
            app.ErrorSwitch.Value = 'Relativo';
            app.EjecutarButton = uibutton(app.NewtonUIFigure, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Text = 'Ejecutar';
            app.MTODODENEWTONLabel = uilabel(app.NewtonUIFigure);
            app.MTODODENEWTONLabel.Text = 'MÉTODO DE NEWTON';
            % Show the figure after all components are created
            app.NewtonUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_newton

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NewtonUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NewtonUIFigure)
        end
    end
end