classdef App_raicesmultiples < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RaicesmultiplesUIFigure        matlab.ui.Figure
        FuncinEditFieldLabel           matlab.ui.control.Label
        FuncinEditField                matlab.ui.control.EditField
        PrimeraderivadaEditFieldLabel  matlab.ui.control.Label
        PrimeraderivadaEditField       matlab.ui.control.EditField
        SegundaderivadaEditFieldLabel  matlab.ui.control.Label
        SegundaderivadaEditField       matlab.ui.control.EditField
        UIAxes                         matlab.ui.control.UIAxes
        MTODODERAICESMLTIPLESLabel     matlab.ui.control.Label
        ToleranciaEditFieldLabel       matlab.ui.control.Label
        ToleranciaEditField            matlab.ui.control.NumericEditField
        IteracionesEditFieldLabel      matlab.ui.control.Label
        IteracionesEditField           matlab.ui.control.NumericEditField
        ErrorSwitchLabel               matlab.ui.control.Label
        ErrorSwitch                    matlab.ui.control.ToggleSwitch
        EjecutarButton                 matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        X0EditFieldLabel               matlab.ui.control.Label
        X0EditField                    matlab.ui.control.NumericEditField
    end

    
    methods (Access = public)
        
        function [resultado] = raicesmultiples_metodo(~,func,funcd,funcd2,x0,tol,iter,err)
            resultado = [];
            f = inline(func);
            d = inline(funcd);
            d2 = inline(funcd2);
            
            errf = "abs(a-b)";
            if err == "Relativo"
                errf = "abs((a-b)/a)";
            end
            er = inline(errf);
            
            if tol <= 0
                msgbox(sprintf("Ingrese una tolerancia valida."));
            else
                if iter <=0
                    msgbox(sprintf("Ingrese un numero de iteraciones valido."));
                else
                    contador = 1;
                    error = tol + 1;
                    y0 = f(x0);
                    d0 = d(x0);
                    dd0 = d2(x0);
                    resultado = [[0,x0,y0,d0,dd0,"---"];];
            
                    while y0 ~= 0 && error > tol && d0 ~= 0 && contador < iter
                        x1 = x0 - ((y0*d0)/((d0^2)-(y0*dd0)));
                        error = er(x1,x0);
                        x0 = x1;
                        y0 = f(x1);
                        d0 = d(x1);
                        dd0 = d2(x1);
                        resultado = [resultado;{contador,x0,y0,d0,dd0,error};];
                        contador = contador + 1;
                    end
            
                    if y0 == 0
                        msgbox(sprintf("%g es raiz.", x0));
                    else
                        msgbox(sprintf("El valor de y es %g",y0));
                        if error < tol
                            msgbox(sprintf("%g es una raiz aproximada.", x0));
                        else
                            if d0 == 0
                                msgbox(sprintf("Problema con la derivada, puede haber raiz multiple."));
                            else
                                msgbox(sprintf("Fracaso en %g iteraciones.", iter));
                            end
                        end
                    end
                    disp(resultado);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
            func = app.FuncinEditField.Value;
            funcd = app.PrimeraderivadaEditField.Value;
            funcd2 = app.SegundaderivadaEditField.Value;
            if func == ""
                msgbox(sprintf("Ingrese una función."));
            else
                fplot(app.UIAxes,func);
                if funcd == ""
                    msgbox(sprintf("Ingrese una primera derivada"));
                else
                    if funcd2 == ""
                        msgbox(sprintf("Ingrese una segunda derivada."));
                    else
                        x0 = app.X0EditField.Value;
                        tol = app.ToleranciaEditField.Value;
                        iter = app.IteracionesEditField.Value;
                        err = app.ErrorSwitch.Value;
                        
                        t = raicesmultiples_metodo(app,func,funcd,funcd2,x0,tol,iter,err);
                        app.UITable.Data = t;
                    end
                end
            end
            
        end

        % Close request function: RaicesmultiplesUIFigure
        function RaicesmultiplesUIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RaicesmultiplesUIFigure = uifigure('Visible', 'off');
            app.RaicesmultiplesUIFigure.Name = 'Raices multiples';
            app.RaicesmultiplesUIFigure.CloseRequestFcn = createCallbackFcn(app, @RaicesmultiplesUIFigureCloseRequest, true);
            app.FuncinEditFieldLabel = uilabel(app.RaicesmultiplesUIFigure);
            app.FuncinEditFieldLabel.Text = 'Función';
            app.FuncinEditField = uieditfield(app.RaicesmultiplesUIFigure, 'text');
            app.PrimeraderivadaEditFieldLabel = uilabel(app.RaicesmultiplesUIFigure);
            app.PrimeraderivadaEditFieldLabel.Text = 'Primera derivada';
            app.PrimeraderivadaEditField = uieditfield(app.RaicesmultiplesUIFigure, 'text');
            app.SegundaderivadaEditFieldLabel = uilabel(app.RaicesmultiplesUIFigure);
            app.SegundaderivadaEditFieldLabel.Text = 'Segunda derivada';
            app.SegundaderivadaEditField = uieditfield(app.RaicesmultiplesUIFigure, 'text');
            app.UIAxes = uiaxes(app.RaicesmultiplesUIFigure);
            title(app.UIAxes, 'f(x)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.MTODODERAICESMLTIPLESLabel = uilabel(app.RaicesmultiplesUIFigure);
            app.MTODODERAICESMLTIPLESLabel.Text = 'MÉTODO DE RAICES MÚLTIPLES';
            app.ToleranciaEditFieldLabel = uilabel(app.RaicesmultiplesUIFigure);
            app.ToleranciaEditFieldLabel.Text = 'Tolerancia';
            app.ToleranciaEditField = uieditfield(app.RaicesmultiplesUIFigure, 'numeric');
            app.IteracionesEditFieldLabel = uilabel(app.RaicesmultiplesUIFigure);
            app.IteracionesEditFieldLabel.Text = 'Iteraciones';
            app.IteracionesEditField = uieditfield(app.RaicesmultiplesUIFigure, 'numeric');
            app.ErrorSwitchLabel = uilabel(app.RaicesmultiplesUIFigure);
            app.ErrorSwitchLabel.Text = 'Error';
            app.ErrorSwitch = uiswitch(app.RaicesmultiplesUIFigure, 'toggle');
            app.ErrorSwitch.Items = {'Absoluto', 'Relativo'};
            app.ErrorSwitch.Value = 'Absoluto';
            app.EjecutarButton = uibutton(app.RaicesmultiplesUIFigure, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Text = 'Ejecutar';
            app.UITable = uitable(app.RaicesmultiplesUIFigure);
            app.UITable.ColumnName = {'n'; 'xn'; 'f(xn)'; 'f''(xn)'; 'f''''(xn)'; 'error'};
            app.UITable.RowName = {};
            app.X0EditFieldLabel = uilabel(app.RaicesmultiplesUIFigure);
            app.X0EditFieldLabel.Text = 'X0';
            app.X0EditField = uieditfield(app.RaicesmultiplesUIFigure, 'numeric');
            % Show the figure after all components are created
            app.RaicesmultiplesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_raicesmultiples

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RaicesmultiplesUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RaicesmultiplesUIFigure)
        end
    end
end