classdef App_sistemas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SistemasdeecuacionesUIFigure  matlab.ui.Figure
        AnlisisNumricoLabel           matlab.ui.control.Label
        SolucindesistemasdeecuacionesLabel  matlab.ui.control.Label
        MtodosdeeliminacinButton      matlab.ui.control.Button
        MtodosdefactorizacinButton    matlab.ui.control.Button
        MtodositerativosButton        matlab.ui.control.Button
        AyudaButton                   matlab.ui.control.Button
        VolverButton                  matlab.ui.control.Button
        GraficadorButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        A = zeros(4,4);
        b = zeros(4,1);
        vars = 4;
    end
    
    methods (Access = private)
        function update_values(app)
            app.A = getappdata(0,'A');
            app.b = getappdata(0,'b');
            app.vars = getappdata(0,'vars');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MtodosdeeliminacinButton
        function MtodosdeeliminacinButtonPushed(app, event)
            app.update_values();
            App_eliminacion(app.A,app.b,app.vars);
        end

        % Button pushed function: MtodosdefactorizacinButton
        function MtodosdefactorizacinButtonPushed(app, event)
            app.update_values();
            App_factorizacion(app.A,app.b,app.vars);
        end

        % Button pushed function: MtodositerativosButton
        function MtodositerativosButtonPushed(app, event)
            app.update_values();
            App_iterativos(app.A,app.b,app.vars);
        end

        % Button pushed function: GraficadorButton
        function GraficadorButtonPushed(app, event)
            App_grapher("");
        end

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            App_Main();
            delete(app);
        end

        % Button pushed function: AyudaButton
        function AyudaButtonPushed(app, event)
            App_ayuda_sistemas("Gauss");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SistemasdeecuacionesUIFigure = uifigure('Visible', 'off');
            app.SistemasdeecuacionesUIFigure.Name = 'Sistemas de ecuaciones';
            app.AnlisisNumricoLabel = uilabel(app.SistemasdeecuacionesUIFigure);
            app.AnlisisNumricoLabel.Text = 'Análisis Numérico';
            app.SolucindesistemasdeecuacionesLabel = uilabel(app.SistemasdeecuacionesUIFigure);
            app.SolucindesistemasdeecuacionesLabel.Text = 'Solución de sistemas de ecuaciones';
            app.MtodosdeeliminacinButton = uibutton(app.SistemasdeecuacionesUIFigure, 'push');
            app.MtodosdeeliminacinButton.ButtonPushedFcn = createCallbackFcn(app, @MtodosdeeliminacinButtonPushed, true);
            app.MtodosdeeliminacinButton.Text = 'Métodos de eliminación';
            app.MtodosdefactorizacinButton = uibutton(app.SistemasdeecuacionesUIFigure, 'push');
            app.MtodosdefactorizacinButton.ButtonPushedFcn = createCallbackFcn(app, @MtodosdefactorizacinButtonPushed, true);
            app.MtodosdefactorizacinButton.Text = 'Métodos de factorización';
            app.MtodositerativosButton = uibutton(app.SistemasdeecuacionesUIFigure, 'push');
            app.MtodositerativosButton.ButtonPushedFcn = createCallbackFcn(app, @MtodositerativosButtonPushed, true);
            app.MtodositerativosButton.Text = 'Métodos iterativos';
            app.AyudaButton = uibutton(app.SistemasdeecuacionesUIFigure, 'push');
            app.AyudaButton.ButtonPushedFcn = createCallbackFcn(app, @AyudaButtonPushed, true);
            app.AyudaButton.Text = 'Ayuda';
            app.VolverButton = uibutton(app.SistemasdeecuacionesUIFigure, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = 'Volver';
            app.GraficadorButton = uibutton(app.SistemasdeecuacionesUIFigure, 'push');
            app.GraficadorButton.ButtonPushedFcn = createCallbackFcn(app, @GraficadorButtonPushed, true);
            app.GraficadorButton.Text = 'Graficador';
            % Show the figure after all components are created
            app.SistemasdeecuacionesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_sistemas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SistemasdeecuacionesUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SistemasdeecuacionesUIFigure)
        end
    end
end